classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        amplitudeKnobLabel  matlab.ui.control.Label
        amplitudeKnob       matlab.ui.control.Knob
        frequencyKnobLabel  matlab.ui.control.Label
        frequencyKnob       matlab.ui.control.Knob
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        UIAxes              matlab.ui.control.UIAxes
        Button              matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             ax=app.UIAxes;
            t=0:0.1:5*pi;
            if isequal(app.DropDown.Value,'sine')
            y=app.amplitudeKnob.Value*sin(2*pi*app.frequencyKnob.Value*t);
            else
                 y=app.amplitudeKnob.Value*cos(2*pi*app.frequencyKnob.Value*t);
            end
            plot(ax,t,y);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.amplitudeKnobLabel = uilabel(app.UIFigure);
            app.amplitudeKnobLabel.Text = 'amplitude';
            app.amplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.frequencyKnobLabel = uilabel(app.UIFigure);
            app.frequencyKnobLabel.Text = 'frequency';
            app.frequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sine', 'cos', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sine';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app5

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end